classdef Main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DiagramTab                      matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        Node234AregroundSupportedLabel  matlab.ui.control.Label
        ThisAppWorksOnlyForaboveTrussLabel  matlab.ui.control.Label
        MaterialTab                     matlab.ui.container.Tab
        YoungsModulusEditFieldLabel     matlab.ui.control.Label
        YoungsModulusEditField          matlab.ui.control.NumericEditField
        AreaEditFieldLabel              matlab.ui.control.Label
        AreaEditField                   matlab.ui.control.NumericEditField
        YoungsModulusinGPaLabel         matlab.ui.control.Label
        Areaincm2Label                  matlab.ui.control.Label
        CoordinatesTab                  matlab.ui.container.Tab
        x1EditFieldLabel                matlab.ui.control.Label
        x1EditField                     matlab.ui.control.NumericEditField
        y1EditFieldLabel                matlab.ui.control.Label
        y1EditField                     matlab.ui.control.NumericEditField
        z1EditFieldLabel                matlab.ui.control.Label
        z1EditField                     matlab.ui.control.NumericEditField
        x2EditFieldLabel                matlab.ui.control.Label
        x2EditField                     matlab.ui.control.NumericEditField
        y2EditFieldLabel                matlab.ui.control.Label
        y2EditField                     matlab.ui.control.NumericEditField
        z2EditFieldLabel                matlab.ui.control.Label
        z2EditField                     matlab.ui.control.NumericEditField
        x3EditFieldLabel                matlab.ui.control.Label
        x3EditField                     matlab.ui.control.NumericEditField
        y3EditFieldLabel                matlab.ui.control.Label
        y3EditField                     matlab.ui.control.NumericEditField
        z3EditFieldLabel                matlab.ui.control.Label
        z3EditField                     matlab.ui.control.NumericEditField
        x4EditFieldLabel                matlab.ui.control.Label
        x4EditField                     matlab.ui.control.NumericEditField
        y4EditFieldLabel                matlab.ui.control.Label
        y4EditField                     matlab.ui.control.NumericEditField
        z4EditFieldLabel                matlab.ui.control.Label
        z4EditField                     matlab.ui.control.NumericEditField
        CoordinatesinmetersLabel        matlab.ui.control.Label
        ForcesTab                       matlab.ui.container.Tab
        Fx1EditFieldLabel               matlab.ui.control.Label
        Fx1EditField                    matlab.ui.control.NumericEditField
        Fy1EditFieldLabel               matlab.ui.control.Label
        Fy1EditField                    matlab.ui.control.NumericEditField
        Fz1EditFieldLabel               matlab.ui.control.Label
        Fz1EditField                    matlab.ui.control.NumericEditField
        Fx2EditFieldLabel               matlab.ui.control.Label
        Fx2EditField                    matlab.ui.control.NumericEditField
        Fy2EditFieldLabel               matlab.ui.control.Label
        Fy2EditField                    matlab.ui.control.NumericEditField
        Fz2EditFieldLabel               matlab.ui.control.Label
        Fz2EditField                    matlab.ui.control.NumericEditField
        Fx3EditFieldLabel               matlab.ui.control.Label
        Fx3EditField                    matlab.ui.control.NumericEditField
        Fy3EditFieldLabel               matlab.ui.control.Label
        Fy3EditField                    matlab.ui.control.NumericEditField
        Fz3EditFieldLabel               matlab.ui.control.Label
        Fz3EditField                    matlab.ui.control.NumericEditField
        Fx4EditFieldLabel               matlab.ui.control.Label
        Fx4EditField                    matlab.ui.control.NumericEditField
        Fy4EditFieldLabel               matlab.ui.control.Label
        Fy4EditField                    matlab.ui.control.NumericEditField
        Fz4EditFieldLabel               matlab.ui.control.Label
        Fz4EditField                    matlab.ui.control.NumericEditField
        BCTab                           matlab.ui.container.Tab
        Ux4EditFieldLabel               matlab.ui.control.Label
        Ux4EditField                    matlab.ui.control.NumericEditField
        Uy4EditFieldLabel               matlab.ui.control.Label
        Uy4EditField                    matlab.ui.control.NumericEditField
        Uz4EditFieldLabel               matlab.ui.control.Label
        Uz4EditField                    matlab.ui.control.NumericEditField
        Ux2EditFieldLabel               matlab.ui.control.Label
        Ux2EditField                    matlab.ui.control.NumericEditField
        Uy2EditFieldLabel               matlab.ui.control.Label
        Uy2EditField                    matlab.ui.control.NumericEditField
        Uz2EditFieldLabel               matlab.ui.control.Label
        Uz2EditField                    matlab.ui.control.NumericEditField
        Ux3EditFieldLabel               matlab.ui.control.Label
        Ux3EditField                    matlab.ui.control.NumericEditField
        Uy3EditFieldLabel               matlab.ui.control.Label
        Uy3EditField                    matlab.ui.control.NumericEditField
        Uz3EditFieldLabel               matlab.ui.control.Label
        Uz3EditField                    matlab.ui.control.NumericEditField
        SolveTrussButton                matlab.ui.control.Button
        ResultTab                       matlab.ui.container.Tab
        Ux1EditField_2Label             matlab.ui.control.Label
        Ux1EditField_2                  matlab.ui.control.NumericEditField
        Uy1EditField_2Label             matlab.ui.control.Label
        Uy1EditField_2                  matlab.ui.control.NumericEditField
        Uz1EditField_2Label             matlab.ui.control.Label
        Uz1EditField_2                  matlab.ui.control.NumericEditField
        PlotDeformedShapeButton         matlab.ui.control.Button
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        RedOrignalShapeLabel            matlab.ui.control.Label
        BlueDeformedShapeLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveTrussButton
        function SolveTrussButtonPushed(app, event)
            E = 10^9*app.YoungsModulusEditField.Value;
            A = (10^-4)*app.AreaEditField.Value;
%             for i = 1:4
%                 xi = app.xiEditField.Value
%                 yi = app.yiEditField.Value
%                 zi = app.ziEditField.Value
%                 Fxi = app.FxiEditField.Value
%                 Fyi = app.FyiEditField.Value
%                 Fzi = app.FziEditField.Value
%             end
                x1 = app.x1EditField.Value
                y1 = app.y1EditField.Value
                z1 = app.z1EditField.Value
                Fx1 = app.Fx1EditField.Value
                Fy1 = app.Fy1EditField.Value
                Fz1 = app.Fz1EditField.Value
                
                x2 = app.x2EditField.Value
                y2 = app.y2EditField.Value
                z2 = app.z2EditField.Value
                Fx2 = app.Fx2EditField.Value
                Fy2 = app.Fy2EditField.Value
                Fz2 = app.Fz2EditField.Value
                
                x3 = app.x3EditField.Value
                y3 = app.y3EditField.Value
                z3 = app.z3EditField.Value
                Fx3 = app.Fx3EditField.Value
                Fy3 = app.Fy3EditField.Value
                Fz3 = app.Fz3EditField.Value
                
                x4 = app.x4EditField.Value
                y4 = app.y4EditField.Value
                z4 = app.z4EditField.Value
                Fx4 = app.Fx4EditField.Value
                Fy4 = app.Fy4EditField.Value
                Fz4 = app.Fz4EditField.Value
%             for i = 1:3
%                 j = i+1;
%                 li = sqrt((xj-x1)^2+(yj-y1)^2+(zj-z1)^2)
%                 Cxi = (x1-xj)/li
%                 Cyi = (y1-yj)/li
%                 Czi = (z1-zj)/li
%                 ki = (A*E)/li
%             end
                
                l1 = sqrt((x2-x1)^2+(y2-y1)^2+(z2-z1)^2)
                Cx1 = (x1-x2)/l1
                Cy1 = (y1-y2)/l1
                Cz1 = (z1-z2)/l1
                k1 = (A*E)/l1
                
                
                l2 = sqrt((x4-x1)^2+(y4-y1)^2+(z4-z1)^2)
                Cx2 = (x1-x4)/l2
                Cy2 = (y1-y4)/l2
                Cz2 = (z1-z4)/l2
                k2 = (A*E)/l2
                
                
                l3 = sqrt((x3-x1)^2+(y3-y1)^2+(z3-z1)^2)
                Cx3 = (x1-x3)/l3
                Cy3 = (y1-y3)/l3
                Cz3 = (z1-z3)/l3
                k3 = (A*E)/l3


%             for i = 1:3
%                 Ki = ki*[Cxi^2    Cxi*Cyi  Cxi*Czi  -Cxi^2   -Cxi*Cyi -Cxi*Czi ;
%                          Cxi*Cyi  Cyi^2    Cyi*Czi  -Cxi*Cyi -Cyi^2   -Cyi*Czi ;
%                          Cxi*Czi  Cyi*Czi  Czi^2    -Cxi*Czi -Cyi*Czi -Czi^2   ;
%                          -Cxi^2   -Cxi*Cyi -Cxi*Czi  Cxi^2   Cxi*Cyi Cxi*Czi   ;
%                          -Cxi*Cyi -Cyi^2   -Cyi*Czi  Cxi*Cyi Cyi^2   Cyi*Czi   ;
%                          -Cxi*Czi -Cyi*Czi -Czi^2    Cxi*Czi Cyi*Czi Czi^2     ;]
%             end
             
                  K1 = k1*[Cx1^2     Cx1*Cy1   Cx1*Cz1  -Cx1^2   -Cx1*Cy1 -Cx1*Cz1 ;
                           Cx1*Cy1   Cy1^2     Cy1*Cz1  -Cx1*Cy1 -Cy1^2   -Cy1*Cz1 ;
                           Cx1*Cz1   Cy1*Cz1   Cz1^2    -Cx1*Cz1 -Cy1*Cz1 -Cz1^2   ;
                          -Cx1^2    -Cx1*Cy1  -Cx1*Cz1  Cx1^2    Cx1*Cy1  Cx1*Cz1   ;
                          -Cx1*Cy1  -Cy1^2    -Cy1*Cz1  Cx1*Cy1  Cy1^2    Cy1*Cz1   ;
                          -Cx1*Cz1  -Cy1*Cz1  -Cz1^2    Cx1*Cz1  Cy1*Cz1  Cz1^2     ;]
                     
                     K2 = k2*[Cx2^2    Cx2*Cy2  Cx2*Cz2  -Cx2^2   -Cx2*Cy2 -Cx2*Cz2 ;
                         Cx2*Cy2  Cy2^2    Cy2*Cz2  -Cx2*Cy2 -Cy2^2   -Cy2*Cz2 ;
                         Cx2*Cz2  Cy2*Cz2  Cz2^2    -Cx2*Cz2 -Cy2*Cz2 -Cz2^2   ;
                         -Cx2^2   -Cx2*Cy2 -Cx2*Cz2  Cx2^2   Cx2*Cy2 Cx2*Cz2   ;
                         -Cx2*Cy2 -Cy2^2   -Cy2*Cz2  Cx2*Cy2 Cy2^2   Cy2*Cz2   ;
                         -Cx2*Cz2 -Cy2*Cz2 -Cz2^2    Cx2*Cz2 Cy2*Cz2 Cz2^2     ;]
                     
                     K3 = k3*[Cx3^3    Cx3*Cy3  Cx3*Cz3  -Cx3^3   -Cx3*Cy3 -Cx3*Cz3 ;
                         Cx3*Cy3  Cy3^3    Cy3*Cz3  -Cx3*Cy3 -Cy3^3   -Cy3*Cz3 ;
                         Cx3*Cz3  Cy3*Cz3  Cz3^3    -Cx3*Cz3 -Cy3*Cz3 -Cz3^3   ;
                         -Cx3^3   -Cx3*Cy3 -Cx3*Cz3  Cx3^3   Cx3*Cy3 Cx3*Cz3   ;
                         -Cx3*Cy3 -Cy3^3   -Cy3*Cz3  Cx3*Cy3 Cy3^3   Cy3*Cz3   ;
                         -Cx3*Cz3 -Cy3*Cz3 -Cz3^3    Cx3*Cz3 Cy3*Cz3 Cz3^3     ;]
             % Applying B.C.
             
%              for i = 1:3
%                 for j = 1:3
%                     kasmbl(i,j) = K1(i+3 , j+3) + K2(i+3 , j+3) + K3(i+3 , j+3)
%                 end
%              end
                 kasmbl(1,1) = K1(1+3 , 1+3) + K2(1+3 , 1+3) + K3(1+3 , 1+3)
                 kasmbl(1,2) = K1(1+3 , 2+3) + K2(1+3 , 2+3) + K3(1+3 , 2+3)
                 kasmbl(1,3) = K1(1+3 , 3+3) + K2(1+3 , 3+3) + K3(1+3 , 3+3)
                  
                 kasmbl(2,1) = K1(2+3 , 1+3) + K2(2+3 , 1+3) + K3(2+3 , 1+3)
                 kasmbl(2,2) = K1(2+3 , 2+3) + K2(2+3 , 2+3) + K3(2+3 , 2+3)
                 kasmbl(2,3) = K1(2+3 , 3+3) + K2(2+3 , 3+3) + K3(2+3 , 3+3)
                     
                 kasmbl(3,1) = K1(3+3 , 1+3) + K2(3+3 , 1+3) + K3(3+3 , 1+3)
                 kasmbl(3,2) = K1(3+3 , 1+3) + K2(3+3 , 1+3) + K3(3+3 , 1+3)
                 kasmbl(3,3) = K1(3+3 , 3+3) + K2(3+3 , 3+3) + K3(3+3 , 3+3)
                        
                        
             F = [Fx1 ; Fy1 ; Fz1]
             U = inv(kasmbl)*F
             app.Ux1EditField_2.Value = U(1,1)
             app.Uy1EditField_2.Value = U(2,1)
             app.Uz1EditField_2.Value = U(3,1)
             
                x11 = app.Ux1EditField_2.Value
                y11 = app.Uy1EditField_2.Value
                z11 = app.Uz1EditField_2.Value
                
                l1 = sqrt((x2-x1)^2+(y2-y1)^2+(z2-z1)^2)
                l11 = sqrt((x2-x11)^2+(y2-y11)^2+(z2-z11)^2)
                
                l2 = sqrt((x4-x1)^2+(y4-y1)^2+(z4-z1)^2)
                l22 = sqrt((x4-x11)^2+(y4-y11)^2+(z4-z11)^2)
                
                l3 = sqrt((x3-x1)^2+(y3-y1)^2+(z3-z1)^2)
                l33 = sqrt((x3-x11)^2+(y3-y11)^2+(z3-z11)^2)
                
                e1 = (l11-l1)/l1
                e2 = (l22-l2)/l2
                e3 = (l33-l3)/l3
                app.EditField.Value = e1
                app.EditField_2.Value = e2
                app.EditField_3.Value = e3
                
                s1 = e1*E
                s2 = e2*E
                s3 = e3*E
                app.EditField_6.Value = s1
                app.EditField_5.Value = s2
                app.EditField_4.Value = s3
                
        end

        % Button pushed function: PlotDeformedShapeButton
        function PlotDeformedShapeButtonPushed(app, event)
                x1 = app.x1EditField.Value
                y1 = app.y1EditField.Value
                z1 = app.z1EditField.Value
                
                x2 = app.x2EditField.Value
                y2 = app.y2EditField.Value
                z2 = app.z2EditField.Value
                
                x3 = app.x3EditField.Value
                y3 = app.y3EditField.Value
                z3 = app.z3EditField.Value
                
                x4 = app.x4EditField.Value
                y4 = app.y4EditField.Value
                z4 = app.z4EditField.Value
                
                x11 = app.Ux1EditField_2.Value
                y11 = app.Uy1EditField_2.Value
                z11 = app.Uz1EditField_2.Value
                plot3([x1,x2 ], [y1,y2],[z1,z2],'r');
                  hold on;
                plot3([x1,x3] , [y1,y3],[z1,z3],'r');
                  hold on;
                plot3([x1,x4] ,[y1,y4],[z1,z4],'r');
                 hold on;
                plot3([x1 + x11 ,x2], [y1 + y11,y2],[z1 + z11,z2],'b');
                 hold on;
                plot3([x1 + x11 ,x3], [y1 + y11,y3],[z1 + z11,z3],'b');
                 hold on;
                plot3([x1 + x11 ,x4] ,[y1 + y11,y4],[z1 + z11,z4],'b');
                 hold on;


        end

        % Callback function
        function StressVariationButtonPushed(app, event)
             E = 10^9*app.YoungsModulusEditField.Value;
            A = (10^-4)*app.AreaEditField.Value;
%             for i = 1:4
%                 xi = app.xiEditField.Value
%                 yi = app.yiEditField.Value
%                 zi = app.ziEditField.Value
%                 Fxi = app.FxiEditField.Value
%                 Fyi = app.FyiEditField.Value
%                 Fzi = app.FziEditField.Value
%             end
                x1 = app.x1EditField.Value
                y1 = app.y1EditField.Value
                z1 = app.z1EditField.Value
                Fx1 = app.Fx1EditField.Value
                Fy1 = app.Fy1EditField.Value
                Fz1 = app.Fz1EditField.Value
                
                x2 = app.x2EditField.Value
                y2 = app.y2EditField.Value
                z2 = app.z2EditField.Value
                Fx2 = app.Fx2EditField.Value
                Fy2 = app.Fy2EditField.Value
                Fz2 = app.Fz2EditField.Value
                
                x3 = app.x3EditField.Value
                y3 = app.y3EditField.Value
                z3 = app.z3EditField.Value
                Fx3 = app.Fx3EditField.Value
                Fy3 = app.Fy3EditField.Value
                Fz3 = app.Fz3EditField.Value
                
                x4 = app.x4EditField.Value
                y4 = app.y4EditField.Value
                z4 = app.z4EditField.Value
                Fx4 = app.Fx4EditField.Value
                Fy4 = app.Fy4EditField.Value
                Fz4 = app.Fz4EditField.Value
%             for i = 1:3
%                 j = i+1;
%                 li = sqrt((xj-x1)^2+(yj-y1)^2+(zj-z1)^2)
%                 Cxi = (x1-xj)/li
%                 Cyi = (y1-yj)/li
%                 Czi = (z1-zj)/li
%                 ki = (A*E)/li
%             end
                
                l1 = sqrt((x2-x1)^2+(y2-y1)^2+(z2-z1)^2)
                Cx1 = (x1-x2)/l1
                Cy1 = (y1-y2)/l1
                Cz1 = (z1-z2)/l1
                k1 = (A*E)/l1
                
                
                l2 = sqrt((x4-x1)^2+(y4-y1)^2+(z4-z1)^2)
                Cx2 = (x1-x4)/l2
                Cy2 = (y1-y4)/l2
                Cz2 = (z1-z4)/l2
                k2 = (A*E)/l2
                
                
                l3 = sqrt((x3-x1)^2+(y3-y1)^2+(z3-z1)^2)
                Cx3 = (x1-x3)/l3
                Cy3 = (y1-y3)/l3
                Cz3 = (z1-z3)/l3
                k3 = (A*E)/l3


%             for i = 1:3
%                 Ki = ki*[Cxi^2    Cxi*Cyi  Cxi*Czi  -Cxi^2   -Cxi*Cyi -Cxi*Czi ;
%                          Cxi*Cyi  Cyi^2    Cyi*Czi  -Cxi*Cyi -Cyi^2   -Cyi*Czi ;
%                          Cxi*Czi  Cyi*Czi  Czi^2    -Cxi*Czi -Cyi*Czi -Czi^2   ;
%                          -Cxi^2   -Cxi*Cyi -Cxi*Czi  Cxi^2   Cxi*Cyi Cxi*Czi   ;
%                          -Cxi*Cyi -Cyi^2   -Cyi*Czi  Cxi*Cyi Cyi^2   Cyi*Czi   ;
%                          -Cxi*Czi -Cyi*Czi -Czi^2    Cxi*Czi Cyi*Czi Czi^2     ;]
%             end
             
                  K1 = k1*[Cx1^2     Cx1*Cy1   Cx1*Cz1  -Cx1^2   -Cx1*Cy1 -Cx1*Cz1 ;
                           Cx1*Cy1   Cy1^2     Cy1*Cz1  -Cx1*Cy1 -Cy1^2   -Cy1*Cz1 ;
                           Cx1*Cz1   Cy1*Cz1   Cz1^2    -Cx1*Cz1 -Cy1*Cz1 -Cz1^2   ;
                          -Cx1^2    -Cx1*Cy1  -Cx1*Cz1  Cx1^2    Cx1*Cy1  Cx1*Cz1   ;
                          -Cx1*Cy1  -Cy1^2    -Cy1*Cz1  Cx1*Cy1  Cy1^2    Cy1*Cz1   ;
                          -Cx1*Cz1  -Cy1*Cz1  -Cz1^2    Cx1*Cz1  Cy1*Cz1  Cz1^2     ;]
                     
                     K2 = k2*[Cx2^2    Cx2*Cy2  Cx2*Cz2  -Cx2^2   -Cx2*Cy2 -Cx2*Cz2 ;
                         Cx2*Cy2  Cy2^2    Cy2*Cz2  -Cx2*Cy2 -Cy2^2   -Cy2*Cz2 ;
                         Cx2*Cz2  Cy2*Cz2  Cz2^2    -Cx2*Cz2 -Cy2*Cz2 -Cz2^2   ;
                         -Cx2^2   -Cx2*Cy2 -Cx2*Cz2  Cx2^2   Cx2*Cy2 Cx2*Cz2   ;
                         -Cx2*Cy2 -Cy2^2   -Cy2*Cz2  Cx2*Cy2 Cy2^2   Cy2*Cz2   ;
                         -Cx2*Cz2 -Cy2*Cz2 -Cz2^2    Cx2*Cz2 Cy2*Cz2 Cz2^2     ;]
                     
                     K3 = k3*[Cx3^3    Cx3*Cy3  Cx3*Cz3  -Cx3^3   -Cx3*Cy3 -Cx3*Cz3 ;
                         Cx3*Cy3  Cy3^3    Cy3*Cz3  -Cx3*Cy3 -Cy3^3   -Cy3*Cz3 ;
                         Cx3*Cz3  Cy3*Cz3  Cz3^3    -Cx3*Cz3 -Cy3*Cz3 -Cz3^3   ;
                         -Cx3^3   -Cx3*Cy3 -Cx3*Cz3  Cx3^3   Cx3*Cy3 Cx3*Cz3   ;
                         -Cx3*Cy3 -Cy3^3   -Cy3*Cz3  Cx3*Cy3 Cy3^3   Cy3*Cz3   ;
                         -Cx3*Cz3 -Cy3*Cz3 -Cz3^3    Cx3*Cz3 Cy3*Cz3 Cz3^3     ;]
             % Applying B.C.
             
%              for i = 1:3
%                 for j = 1:3
%                     kasmbl(i,j) = K1(i+3 , j+3) + K2(i+3 , j+3) + K3(i+3 , j+3)
%                 end
%              end
                 kasmbl(1,1) = K1(1+3 , 1+3) + K2(1+3 , 1+3) + K3(1+3 , 1+3)
                 kasmbl(1,2) = K1(1+3 , 2+3) + K2(1+3 , 2+3) + K3(1+3 , 2+3)
                 kasmbl(1,3) = K1(1+3 , 3+3) + K2(1+3 , 3+3) + K3(1+3 , 3+3)
                  
                 kasmbl(2,1) = K1(2+3 , 1+3) + K2(2+3 , 1+3) + K3(2+3 , 1+3)
                 kasmbl(2,2) = K1(2+3 , 2+3) + K2(2+3 , 2+3) + K3(2+3 , 2+3)
                 kasmbl(2,3) = K1(2+3 , 3+3) + K2(2+3 , 3+3) + K3(2+3 , 3+3)
                     
                 kasmbl(3,1) = K1(3+3 , 1+3) + K2(3+3 , 1+3) + K3(3+3 , 1+3)
                 kasmbl(3,2) = K1(3+3 , 1+3) + K2(3+3 , 1+3) + K3(3+3 , 1+3)
                 kasmbl(3,3) = K1(3+3 , 3+3) + K2(3+3 , 3+3) + K3(3+3 , 3+3)
                        
                        
             F = [Fx1 ; Fy1 ; Fz1]
             U = inv(kasmbl)*F
             app.Ux1EditField_2.Value = U(1,1)
             app.Uy1EditField_2.Value = U(2,1)
             app.Uz1EditField_2.Value = U(3,1)
             
                x11 = app.Ux1EditField_2.Value
                y11 = app.Uy1EditField_2.Value
                z11 = app.Uz1EditField_2.Value
                
                l1 = sqrt((x2-x1)^2+(y2-y1)^2+(z2-z1)^2)
                l11 = sqrt((x2-x11)^2+(y2-y11)^2+(z2-z11)^2)
                
                l2 = sqrt((x4-x1)^2+(y4-y1)^2+(z4-z1)^2)
                l22 = sqrt((x4-x11)^2+(y4-y11)^2+(z4-z11)^2)
                
                l3 = sqrt((x3-x1)^2+(y3-y1)^2+(z3-z1)^2)
                l33 = sqrt((x3-x11)^2+(y3-y11)^2+(z3-z11)^2)
                
                e1 = (l11-l1)/l1
                e2 = (l22-l2)/l2
                e3 = (l33-l3)/l3
                app.EditField.Value = e1
                app.EditField_2.Value = e2
                app.EditField_3.Value = e3
                
                s1 = e1*E
                s2 = e2*E
                s3 = e3*E
                app.EditField_6.Value = s1
                app.EditField_5.Value = s2
                app.EditField_4.Value = s3
                
%                 if (s1 > s2 && s1 > s3 )
%                   if () 
%                 plot3([x1,x2 ], [y1,y2],[z1,z2],'r');
%                   hold on;
%                 plot3([x1,x3] , [y1,y3],[z1,z3],'r');
%                   hold on;
%                 plot3([x1,x4] ,[y1,y4],[z1,z4],'r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiagramTab = uitab(app.TabGroup);
            app.DiagramTab.Title = 'Diagram';
            app.ThisAppWorksOnlyForaboveTrussLabel = uilabel(app.DiagramTab);
            app.ThisAppWorksOnlyForaboveTrussLabel.Text = 'This App Works Only For above Truss';
            app.Node234AregroundSupportedLabel = uilabel(app.DiagramTab);
            app.Node234AregroundSupportedLabel.Text = 'Node 2,3,4 Are ground Supported';
            app.Image = uiimage(app.DiagramTab);
            app.Image.ImageSource = '3_D_Truss-Model.jpg';
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material ';
            app.YoungsModulusEditFieldLabel = uilabel(app.MaterialTab);
            app.YoungsModulusEditFieldLabel.Text = 'YoungsModulus ';
            app.YoungsModulusEditField = uieditfield(app.MaterialTab, 'numeric');
            app.AreaEditFieldLabel = uilabel(app.MaterialTab);
            app.AreaEditFieldLabel.Text = 'Area ';
            app.AreaEditField = uieditfield(app.MaterialTab, 'numeric');
            app.YoungsModulusinGPaLabel = uilabel(app.MaterialTab);
            app.YoungsModulusinGPaLabel.Text = 'Youngs Modulus in GPa';
            app.Areaincm2Label = uilabel(app.MaterialTab);
            app.Areaincm2Label.Text = 'Area in cm^2';
            app.CoordinatesTab = uitab(app.TabGroup);
            app.CoordinatesTab.Title = 'Coordinates';
            app.x1EditFieldLabel = uilabel(app.CoordinatesTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.y1EditFieldLabel = uilabel(app.CoordinatesTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.z1EditFieldLabel = uilabel(app.CoordinatesTab);
            app.z1EditFieldLabel.Text = 'z1';
            app.z1EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.x2EditFieldLabel = uilabel(app.CoordinatesTab);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.y2EditFieldLabel = uilabel(app.CoordinatesTab);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.z2EditFieldLabel = uilabel(app.CoordinatesTab);
            app.z2EditFieldLabel.Text = 'z2';
            app.z2EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.x3EditFieldLabel = uilabel(app.CoordinatesTab);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.y3EditFieldLabel = uilabel(app.CoordinatesTab);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.z3EditFieldLabel = uilabel(app.CoordinatesTab);
            app.z3EditFieldLabel.Text = 'z3';
            app.z3EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.x4EditFieldLabel = uilabel(app.CoordinatesTab);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.y4EditFieldLabel = uilabel(app.CoordinatesTab);
            app.y4EditFieldLabel.Text = 'y4';
            app.y4EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.z4EditFieldLabel = uilabel(app.CoordinatesTab);
            app.z4EditFieldLabel.Text = 'z4';
            app.z4EditField = uieditfield(app.CoordinatesTab, 'numeric');
            app.CoordinatesinmetersLabel = uilabel(app.CoordinatesTab);
            app.CoordinatesinmetersLabel.Text = 'Coordinates in meters';
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            app.Fx1EditFieldLabel = uilabel(app.ForcesTab);
            app.Fx1EditFieldLabel.Text = 'Fx1';
            app.Fx1EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fy1EditFieldLabel = uilabel(app.ForcesTab);
            app.Fy1EditFieldLabel.Text = 'Fy1';
            app.Fy1EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fz1EditFieldLabel = uilabel(app.ForcesTab);
            app.Fz1EditFieldLabel.Text = 'Fz1';
            app.Fz1EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fx2EditFieldLabel = uilabel(app.ForcesTab);
            app.Fx2EditFieldLabel.Text = 'Fx2';
            app.Fx2EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fy2EditFieldLabel = uilabel(app.ForcesTab);
            app.Fy2EditFieldLabel.Text = 'Fy2';
            app.Fy2EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fz2EditFieldLabel = uilabel(app.ForcesTab);
            app.Fz2EditFieldLabel.Text = 'Fz2';
            app.Fz2EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fx3EditFieldLabel = uilabel(app.ForcesTab);
            app.Fx3EditFieldLabel.Text = 'Fx3';
            app.Fx3EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fy3EditFieldLabel = uilabel(app.ForcesTab);
            app.Fy3EditFieldLabel.Text = 'Fy3';
            app.Fy3EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fz3EditFieldLabel = uilabel(app.ForcesTab);
            app.Fz3EditFieldLabel.Text = 'Fz3';
            app.Fz3EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fx4EditFieldLabel = uilabel(app.ForcesTab);
            app.Fx4EditFieldLabel.Text = 'Fx4';
            app.Fx4EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fy4EditFieldLabel = uilabel(app.ForcesTab);
            app.Fy4EditFieldLabel.Text = 'Fy4';
            app.Fy4EditField = uieditfield(app.ForcesTab, 'numeric');
            app.Fz4EditFieldLabel = uilabel(app.ForcesTab);
            app.Fz4EditFieldLabel.Text = 'Fz4';
            app.Fz4EditField = uieditfield(app.ForcesTab, 'numeric');
            app.BCTab = uitab(app.TabGroup);
            app.BCTab.Title = 'B.C.';
            app.Ux4EditFieldLabel = uilabel(app.BCTab);
            app.Ux4EditFieldLabel.Text = 'Ux4';
            app.Ux4EditField = uieditfield(app.BCTab, 'numeric');
            app.Uy4EditFieldLabel = uilabel(app.BCTab);
            app.Uy4EditFieldLabel.Text = 'Uy4';
            app.Uy4EditField = uieditfield(app.BCTab, 'numeric');
            app.Uz4EditFieldLabel = uilabel(app.BCTab);
            app.Uz4EditFieldLabel.Text = 'Uz4';
            app.Uz4EditField = uieditfield(app.BCTab, 'numeric');
            app.Ux2EditFieldLabel = uilabel(app.BCTab);
            app.Ux2EditFieldLabel.Text = 'Ux2';
            app.Ux2EditField = uieditfield(app.BCTab, 'numeric');
            app.Uy2EditFieldLabel = uilabel(app.BCTab);
            app.Uy2EditFieldLabel.Text = 'Uy2';
            app.Uy2EditField = uieditfield(app.BCTab, 'numeric');
            app.Uz2EditFieldLabel = uilabel(app.BCTab);
            app.Uz2EditFieldLabel.Text = 'Uz2';
            app.Uz2EditField = uieditfield(app.BCTab, 'numeric');
            app.Ux3EditFieldLabel = uilabel(app.BCTab);
            app.Ux3EditFieldLabel.Text = 'Ux3';
            app.Ux3EditField = uieditfield(app.BCTab, 'numeric');
            app.Uy3EditFieldLabel = uilabel(app.BCTab);
            app.Uy3EditFieldLabel.Text = 'Uy3';
            app.Uy3EditField = uieditfield(app.BCTab, 'numeric');
            app.Uz3EditFieldLabel = uilabel(app.BCTab);
            app.Uz3EditFieldLabel.Text = 'Uz3';
            app.Uz3EditField = uieditfield(app.BCTab, 'numeric');
            app.SolveTrussButton = uibutton(app.BCTab, 'push');
            app.SolveTrussButton.ButtonPushedFcn = createCallbackFcn(app, @SolveTrussButtonPushed, true);
            app.SolveTrussButton.Text = 'Solve Truss';
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.Ux1EditField_2Label = uilabel(app.ResultTab);
            app.Ux1EditField_2Label.Text = 'Ux1';
            app.Ux1EditField_2 = uieditfield(app.ResultTab, 'numeric');
            app.Uy1EditField_2Label = uilabel(app.ResultTab);
            app.Uy1EditField_2Label.Text = 'Uy1';
            app.Uy1EditField_2 = uieditfield(app.ResultTab, 'numeric');
            app.Uz1EditField_2Label = uilabel(app.ResultTab);
            app.Uz1EditField_2Label.Text = 'Uz1';
            app.Uz1EditField_2 = uieditfield(app.ResultTab, 'numeric');
            app.PlotDeformedShapeButton = uibutton(app.ResultTab, 'push');
            app.PlotDeformedShapeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDeformedShapeButtonPushed, true);
            app.PlotDeformedShapeButton.Text = 'Plot Deformed Shape';
            app.EditField_3Label = uilabel(app.ResultTab);
            app.EditField_3Label.Text = 'є3';
            app.EditField_3 = uieditfield(app.ResultTab, 'numeric');
            app.EditField_2Label = uilabel(app.ResultTab);
            app.EditField_2Label.Text = 'є2';
            app.EditField_2 = uieditfield(app.ResultTab, 'numeric');
            app.EditFieldLabel = uilabel(app.ResultTab);
            app.EditFieldLabel.Text = 'є1';
            app.EditField = uieditfield(app.ResultTab, 'numeric');
            app.Label_3 = uilabel(app.ResultTab);
            app.Label_3.Text = 'σ3';
            app.EditField_4 = uieditfield(app.ResultTab, 'numeric');
            app.Label_2 = uilabel(app.ResultTab);
            app.Label_2.Text = ' σ2';
            app.EditField_5 = uieditfield(app.ResultTab, 'numeric');
            app.Label = uilabel(app.ResultTab);
            app.Label.Text = 'σ1';
            app.EditField_6 = uieditfield(app.ResultTab, 'numeric');
            app.RedOrignalShapeLabel = uilabel(app.ResultTab);
            app.RedOrignalShapeLabel.Text = 'Red - Orignal Shape';
            app.BlueDeformedShapeLabel = uilabel(app.ResultTab);
            app.BlueDeformedShapeLabel.Text = 'Blue - Deformed Shape';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end